classdef Project_GUI_V5_M < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        yscaleFFTEditField           matlab.ui.control.NumericEditField
        yscaleFFTEditFieldLabel      matlab.ui.control.Label
        yscaleEditField              matlab.ui.control.NumericEditField
        yscaleEditFieldLabel         matlab.ui.control.Label
        xscaleFFTEditField           matlab.ui.control.NumericEditField
        xscaleFFTEditFieldLabel      matlab.ui.control.Label
        Icons                        matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        BPM_Indicator                matlab.ui.control.Label
        ScaleButton                  matlab.ui.control.Button
        xscaleEditField              matlab.ui.control.NumericEditField
        xscaleEditFieldLabel         matlab.ui.control.Label
        SignalDropDown               matlab.ui.control.DropDown
        SignalDropDownLabel          matlab.ui.control.Label
        BPM                          matlab.ui.control.Label
        ConnectButton_2              matlab.ui.control.Button
        ContinueButton               matlab.ui.control.Button
        STOPButton                   matlab.ui.control.Button
        FilterButton                 matlab.ui.control.Button
        LowFrequencyLimitEditField   matlab.ui.control.NumericEditField
        LowFrequencyLimitEditFieldLabel  matlab.ui.control.Label
        HighFrequencyLimitEditField  matlab.ui.control.NumericEditField
        HighFrequencyLimitEditFieldLabel  matlab.ui.control.Label
        FilterTypeKnob               matlab.ui.control.DiscreteKnob
        FilterTyLabel                matlab.ui.control.Label
        FilterOrderKnob              matlab.ui.control.DiscreteKnob
        FilterOrderKnobLabel         matlab.ui.control.Label
        RefreshButton                matlab.ui.control.Button
        SerialPortDropDown           matlab.ui.control.DropDown
        SerialPortDropDownLabel      matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_4                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        run = 1;
        serialPortObj
        filtering = 0;
        a
        b
        data
        bits
        num_groups
        first_12bit
        second_12bit
        heartData
        lungData
        samplingFreq = 10000
        bpm = 0
        xscale
        xscaleFFT
        yscale
        yscaleFFT
        datascale
        bpmtreshold
    end
    
    methods (Access = private)
        function refreshSerialPorts(app)
            % Get the list of available serial ports
            availablePorts = serialportlist;
        
            % Update the dropdown items
            if ~isempty(availablePorts)
                app.SerialPortDropDown.Items = availablePorts;
                %app.SerialPortDropDown.Value = availablePorts{1}; % Default to the first port
            else
                app.SerialPortDropDown.Items = {'No Ports Available'};
                app.SerialPortDropDown.Value = 'No Ports Available';
                app.serialPortObj = [];
            end
        end

        function serialDataCallback(app, src)
            try
                if app.run == 1
                    % Read available data
                    app.data = read(src, src.NumBytesAvailable, "uint8");
                    % Find indices where the pattern [255, 255, 10] starts
                    removeIndices = find(app.data(1:end-2) == 255 & app.data(2:end-1) == 255 & app.data(3:end) == 10);
                    
                    % Remove the full [255, 255, 10] pattern
                    app.data([removeIndices, removeIndices + 1, removeIndices + 2]) = [];
                    app.num_groups = floor(length(app.data) / 3);
                    if app.num_groups > 0
                        
                        % Preallocate arrays
                        app.first_12bit = zeros(1, app.num_groups);
                        app.second_12bit = zeros(1, app.num_groups);
    
                        % Process data in groups of 3 bytes
                        for i = 1:app.num_groups
                            idx = (i - 1) * 3 + 1;
                            byte1 = app.data(idx);
                            byte2 = app.data(idx + 1);
                            byte3 = app.data(idx + 2);
                           
                            % Calculate 12-bit numbers
                            app.first_12bit(i) = bitshift(uint16(byte1), 4) + bitshift(bitand(uint16(byte2), 240), -4);
                            app.second_12bit(i) = bitshift(bitand(uint16(byte2), 15), 8) + uint16(byte3);
                        end

                        app.heartData = [app.heartData(length(app.first_12bit):app.datascale),app.first_12bit];
                        app.lungData = [app.lungData(length(app.second_12bit):app.datascale),app.second_12bit];
                        switch app.SignalDropDown.Value
                            case 'Heart Data'
                                title(app.UIAxes, "Raw Heart Signal");
                                title(app.UIAxes_2, "Filtered Heart Signal");
                                title(app.UIAxes_3, "Frequency Analysis of Raw Heart Signal");
                                title(app.UIAxes_4, "Frequency Analysis of Filtered Heart Signal");
                                app.Icons.ImageSource = './Heart_Power_Pop.png';
                                ShowData(app,app.heartData)
                                
                            case 'Lung Data'
                                title(app.UIAxes, "Raw Lung Signal");
                                title(app.UIAxes_2, "Filtered Lung Signal");
                                title(app.UIAxes_3, "Frequency Analysis of Raw Lung Signal");
                                title(app.UIAxes_4, "Frequency Analysis of Filtered Lung Signal");
                                app.Icons.ImageSource = './Lung.png';
                                ShowData(app,app.lungData)
                        end
                    end
                end
            catch e
                %warning('Error in serialDataCallback: %s', e.message);
            end
        end
        function ShowData(app,data)
            % Update plots
            time_vector = 1:(app.xscale);
            ylim(app.UIAxes,[0,app.yscale])
            plot(app.UIAxes, time_vector, data, 'r');
            L = length(data);
            f = app.samplingFreq*(0:(L/2))/L;

            Y_raw = fft(data);
            P2_raw = abs(Y_raw/L);
            P1_raw = P2_raw(1:floor(L/2+1));
            P1_raw(2:end-1) = 2*P1_raw(2:end-1);
            plot(app.UIAxes_3, f, P1_raw, 'b');
            xlim(app.UIAxes_3, [0 app.xscaleFFT]);
            ylim(app.UIAxes_3, [0 app.yscaleFFT]);
            drawnow;

            % Apply filter if enabled
            if app.filtering == 1
                filtered_data = filtfilt(app.b, app.a, data);
                ylim(app.UIAxes_2,[0,4096])
                plot(app.UIAxes_2, 1:length(filtered_data), filtered_data, 'r');

                % Calculate FFT for raw and filtered data
                L = length(data);
                f = app.samplingFreq*(0:(L/2))/L;

                Y_filtered = fft(filtered_data);
                P2_filtered = abs(Y_filtered/L);
                P1_filtered = P2_filtered(1:floor(L/2+1));
                P1_filtered(2:end-1) = 2*P1_filtered(2:end-1);

                % Plot FFT results
                plot(app.UIAxes_4, f, P1_filtered, 'b');
                xlim(app.UIAxes_4, [0 app.xscaleFFT]);
                ylim(app.UIAxes_4, [0 app.yscaleFFT]);
                drawnow;
            end
            % Calculate BPM or Breath Rate
            minHeight = mean(filtered_data) + std(filtered_data);
            [~, locs] = findpeaks(filtered_data, 'MinPeakHeight', minHeight, 'MinPeakDistance', app.samplingFreq * 0.5);
            peakIntervals = diff(locs) / app.samplingFreq; % Convert to seconds
            if ~isempty(peakIntervals)
                avgInterval = mean(peakIntervals);
                if strcmp(app.SignalDropDown.Value, 'Heart Data')
                    app.bpm = 60 / avgInterval; % Calculate BPM
                    app.BPM.Text = sprintf('%.2f', floor(app.bpm));
                elseif strcmp(app.SignalDropDown.Value, 'Lung Data')
                    app.bpm = 60 / avgInterval; % Calculate Breathing Rate
                    app.BPM.Text = sprintf('%.2f', floor(app.bpm));
                end
            else
                app.BPM.Text = 'No BPM';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SignalDropDown.Items  = ["Heart Data","Lung Data"];
            app.xscale = 20000;
            app.xscaleFFT = 5000;
            app.yscale = 4096;
            app.yscaleFFT = 2000;
            app.datascale = app.xscale-1;
            app.heartData = zeros(1,app.datascale);
            app.lungData = zeros(1,app.datascale);
            refreshSerialPorts(app);
        end

        % Close request function: UIFigure
        function CloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: ConnectButton_2
        function ConnectButtonPushed(app, event)
            %Aşağıda hata ve mesajların uyarı olarak değil, text
            %güncelleyecek şekilde güncellenmesi lazım
            selectedPort = app.SerialPortDropDown.Value;
            if strcmp(selectedPort, 'No Ports Available')
            uialert(app.UIFigure, 'No valid port selected!', 'Error');
            elseif (~isempty(app.serialPortObj) && isvalid(app.serialPortObj))
                %Do nothing if already connected
            else
                try
                    app.serialPortObj = serialport(selectedPort, 250000);%,Timeout=1); % Adjust baud rate
                    configureTerminator(app.serialPortObj, "LF"); %LF=Line Return, CR=Carrier Return 
                    configureCallback(app.serialPortObj, 'terminator', @(src, ~) serialDataCallback(app, src));
                    uialert(app.UIFigure, ['Connected to ' selectedPort], 'Success');
                catch msg
                    uialert(app.UIFigure, msg.message, 'Connection Error');
                end
            end
            %configureCallback(app.serialPortObj, "byte", 1000, @(src, ~) readData(app));
            
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
        app.filtering = 1;
        %app.FilterImage.ImageSource = 'filteron.svg';
        %Aşağıdaki frekans değerleri, sampling frekansının yarısına bölümü olacak şekilde ayarlanacak (Nyquist) 
        lowFreq = app.LowFrequencyLimitEditField.Value / (app.samplingFreq / 2);
        highFreq = app.HighFrequencyLimitEditField.Value / (app.samplingFreq / 2);
        switch app.FilterTypeKnob.Value 
            case 'LowPass'
                [app.b, app.a] = butter(str2double(app.FilterOrderKnob.Value), lowFreq, 'low');
            case 'HighPass'
                [app.b, app.a] = butter(str2double(app.FilterOrderKnob.Value), highFreq, 'high');
            case 'BandPass'
                [app.b, app.a] = butter(str2double(app.FilterOrderKnob.Value), [highFreq lowFreq], 'bandpass');
            case 'BandStop'
                [app.b, app.a] = butter(str2double(app.FilterOrderKnob.Value), [highFreq lowFreq], 'stop');
        end
        end

        % Button pushed function: STOPButton
        function StopButtonPushed(app, event)
        app.run = 0;
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.run = 1;
            cla(app.UIAxes); cla(app.UIAxes_2); cla(app.UIAxes_3); cla(app.UIAxes_4);
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            refreshSerialPorts(app)
        end

        % Button pushed function: ScaleButton
        function ScaleButtonPushed(app, event)
            app.xscale = app.xscaleEditField.Value;
            app.yscale = app.yscaleEditField.Value;
            app.yscaleFFT = app.yscaleFFTEditField.Value;
            app.xscaleFFT = app.xscaleFFTEditField.Value;
            if app.xscaleFFT > 5000
                app.xscaleFFT = 5000;
            end
            app.datascale = app.xscale-1;
            app.heartData = zeros(1,app.datascale);
            app.lungData = zeros(1,app.datascale);
            cla(app.UIAxes); cla(app.UIAxes_2); cla(app.UIAxes_3); cla(app.UIAxes_4);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequest, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SerialPortDropDownLabel = uilabel(app.UIFigure);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.UIFigure);
            app.SerialPortDropDown.Items = {};
            app.SerialPortDropDown.Value = {};
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.FilterOrderKnobLabel = uilabel(app.UIFigure);
            app.FilterOrderKnobLabel.Text = 'Filter Order';
            app.FilterOrderKnob = uiknob(app.UIFigure, 'discrete');
            app.FilterOrderKnob.Items = {'1', '2', '3', '4', '5'};
            app.FilterOrderKnob.Value = '1';
            app.FilterTyLabel = uilabel(app.UIFigure);
            app.FilterTyLabel.Text = 'Filter Type';
            app.FilterTypeKnob = uiknob(app.UIFigure, 'discrete');
            app.FilterTypeKnob.Items = {'LowPass', 'HighPass', 'BandPass', 'StopBand'};
            app.FilterTypeKnob.Value = 'LowPass';
            app.HighFrequencyLimitEditFieldLabel = uilabel(app.UIFigure);
            app.HighFrequencyLimitEditFieldLabel.Text = 'High Frequency Limit';
            app.HighFrequencyLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowFrequencyLimitEditFieldLabel = uilabel(app.UIFigure);
            app.LowFrequencyLimitEditFieldLabel.Text = 'Low Frequency Limit';
            app.LowFrequencyLimitEditField = uieditfield(app.UIFigure, 'numeric');
            app.FilterButton = uibutton(app.UIFigure, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.ConnectButton_2 = uibutton(app.UIFigure, 'push');
            app.ConnectButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton_2.Text = 'Connect';
            app.BPM = uilabel(app.UIFigure);
            app.BPM.Text = 'BPM';
            app.SignalDropDownLabel = uilabel(app.UIFigure);
            app.SignalDropDownLabel.Text = 'Signal';
            app.SignalDropDown = uidropdown(app.UIFigure);
            app.SignalDropDown.Items = {};
            app.SignalDropDown.Value = {};
            app.xscaleEditFieldLabel = uilabel(app.UIFigure);
            app.xscaleEditFieldLabel.Text = 'xscale';
            app.xscaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.xscaleEditField.Value = 20000;
            app.ScaleButton = uibutton(app.UIFigure, 'push');
            app.ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleButtonPushed, true);
            app.ScaleButton.Text = 'Scale';
            app.BPM_Indicator = uilabel(app.UIFigure);
            app.BPM_Indicator.Text = 'BPM:';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'AGU_Logo_Png.png');
            app.Icons = uiimage(app.UIFigure);
            app.Icons.ImageSource = fullfile(pathToMLAPP, 'Heart_Power_Pop.png');
            app.xscaleFFTEditFieldLabel = uilabel(app.UIFigure);
            app.xscaleFFTEditFieldLabel.Text = 'xscaleFFT';
            app.xscaleFFTEditField = uieditfield(app.UIFigure, 'numeric');
            app.xscaleFFTEditField.Value = 5000;
            app.yscaleEditFieldLabel = uilabel(app.UIFigure);
            app.yscaleEditFieldLabel.Text = 'yscale';
            app.yscaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.yscaleEditField.Value = 4096;
            app.yscaleFFTEditFieldLabel = uilabel(app.UIFigure);
            app.yscaleFFTEditFieldLabel.Text = 'yscaleFFT';
            app.yscaleFFTEditField = uieditfield(app.UIFigure, 'numeric');
            app.yscaleFFTEditField.Value = 2000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project_GUI_V5_M

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end