classdef sapa_bayabifinal_yusuf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        ConnectButton_2               matlab.ui.control.Button
        ContinueButton                matlab.ui.control.Button
        STOPButton                    matlab.ui.control.Button
        FilterButton                  matlab.ui.control.Button
        LowCutoffFrequencyEditField   matlab.ui.control.NumericEditField
        LowCutoffFrequencyEditFieldLabel  matlab.ui.control.Label
        HighCutoffFrequencyEditField  matlab.ui.control.NumericEditField
        HighCutoffFrequencyEditFieldLabel  matlab.ui.control.Label
        FilterTypeKnob                matlab.ui.control.DiscreteKnob
        FilterOrderKnob_2Label        matlab.ui.control.Label
        FilterOrderKnob               matlab.ui.control.DiscreteKnob
        FilterOrderKnobLabel          matlab.ui.control.Label
        RefreshButton                 matlab.ui.control.Button
        SerialPortDropDown            matlab.ui.control.DropDown
        SerialPortDropDownLabel       matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes_4                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        run = 1;
        serialPortObj
        filtering = 0;
        a
        b
        data
        bits
        num_groups
        first_12bit
        second_12bit
        heartData = zeros(1,19999)
        lungData = zeros(1,19999)
    end
    
    methods (Access = private)
        function refreshSerialPorts(app)
            % Get the list of available serial ports
            availablePorts = serialportlist;
        
            % Update the dropdown items
            if ~isempty(availablePorts)
                app.SerialPortDropDown.Items = availablePorts;
                %app.SerialPortDropDown.Value = availablePorts{1}; % Default to the first port
            else
                app.SerialPortDropDown.Items = {'No Ports Available'};
                app.SerialPortDropDown.Value = 'No Ports Available';
                app.serialPortObj = [];
            end
        end

        function serialDataCallback(app, src)
        try
            if app.run == 1
                % Read available data
                app.data = read(src, src.NumBytesAvailable, "uint8");
                % Find indices where the pattern [255, 255, 10] starts
                removeIndices = find(app.data(1:end-2) == 255 & app.data(2:end-1) == 255 & app.data(3:end) == 10);
                
                % Remove the full [255, 255, 10] pattern
                app.data([removeIndices, removeIndices + 1, removeIndices + 2]) = [];
                app.num_groups = floor(length(app.data) / 3);
                if app.num_groups > 0
                    
                    % Preallocate arrays
                    app.first_12bit = zeros(1, app.num_groups);
                    app.second_12bit = zeros(1, app.num_groups);

                    % Process data in groups of 3 bytes
                    for i = 1:app.num_groups
                        idx = (i - 1) * 3 + 1;
                        byte1 = app.data(idx);
                        byte2 = app.data(idx + 1);
                        byte3 = app.data(idx + 2);
                        %disp([byte1, byte2, byte3]);
                       

                        % Calculate 12-bit numbers
                        app.first_12bit(i) = bitshift(uint16(byte1), 4) + bitshift(bitand(uint16(byte2), 240), -4);
                        app.second_12bit(i) = bitshift(bitand(uint16(byte2), 15), 8) + uint16(byte3);
                        %disp([app.first_12bit(i), app.second_12bit(i)]);
                    end
                    app.Label.Text = num2str(length(app.first_12bit));
                    app.heartData = [app.heartData(length(app.first_12bit):19999),app.first_12bit];
                    app.lungData = [app.lungData(length(app.second_12bit):19999),app.second_12bit];
                    app.Label_2.Text = num2str(length(app.lungData));
                    % Update plots
                    time_vector = 1:20000;
                    ylim(app.UIAxes,[0,4096])
                    plot(app.UIAxes, time_vector, app.lungData, 'r');
                    drawnow;
                    
                    % Apply filter if enabled
                    if app.filtering == 1
                        filtered_data = filtfilt(app.b, app.a, app.first_12bit);
                        plot(app.UIAxes_2, 1:length(filtered_data), filtered_data, 'r');
                        
                        % Calculate FFT for raw and filtered data
                        Fs = 10000; % Your sampling frequency
                        L = length(app.first_12bit);
                        f = Fs*(0:(L/2))/L;
                        
                        Y_raw = fft(app.first_12bit);
                        P2_raw = abs(Y_raw/L);
                        P1_raw = P2_raw(1:floor(L/2+1));
                        P1_raw(2:end-1) = 2*P1_raw(2:end-1);
                        
                        Y_filtered = fft(filtered_data);
                        P2_filtered = abs(Y_filtered/L);
                        P1_filtered = P2_filtered(1:floor(L/2+1));
                        P1_filtered(2:end-1) = 2*P1_filtered(2:end-1);
                        
                        % Plot FFT results
                        plot(app.UIAxes_3, f, P1_raw, 'y');
                        plot(app.UIAxes_4, f, P1_filtered, 'y');
                        drawnow;
                    end
                end
            end
        catch e
            %warning('Error in serialDataCallback: %s', e.message);
        end
    end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            title(app.UIAxes, "Raw Heart Signal");
            title(app.UIAxes_2, "Filtered Heart Signal");
            title(app.UIAxes_3, "Frequency Analysis of Raw Heart Signal");
            title(app.UIAxes_4, "Frequency Analysis of Filtered Heart Signal");
            refreshSerialPorts(app);
        end

        % Close request function: UIFigure
        function CloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: ConnectButton_2
        function ConnectButtonPushed(app, event)
            %Aşağıda hata ve mesajların uyarı olarak değil, text
            %güncelleyecek şekilde güncellenmesi lazım
            selectedPort = app.SerialPortDropDown.Value;
            if strcmp(selectedPort, 'No Ports Available')
            uialert(app.UIFigure, 'No valid port selected!', 'Error');
            elseif (~isempty(app.serialPortObj) && isvalid(app.serialPortObj))
                %Do nothing if already connected
            else
                try
                    app.serialPortObj = serialport(selectedPort, 250000);%,Timeout=1); % Adjust baud rate as needed
                    configureTerminator(app.serialPortObj, "LF"); %LF=Line Return, CR=Carrier Return 
                    configureCallback(app.serialPortObj, 'terminator', @(src, ~) serialDataCallback(app, src));
                    uialert(app.UIFigure, ['Connected to ' selectedPort], 'Success');
                catch msg
                    uialert(app.UIFigure, msg.message, 'Connection Error');
                end
            end
            %configureCallback(app.serialPortObj, "byte", 1000, @(src, ~) readData(app));
            
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
        app.filtering = 1;
        %app.FilterImage.ImageSource = 'filteron.svg';
        %Aşağıdaki frekans değerleri, sampling frekansının yarısına bölümü olacak şekilde ayarlanacak (Nyquist) 
        switch app.FilterTypeKnob.Value 
            case 'LowPass'
                [app.b, app.a] = butter(str2double(app.FilterOrderKnob.Value), app.LowCutoffFrequencyEditField.Value/(10000/2), 'low');
            case 'HighPass'
                [app.b, app.a] = butter(str2double(app.FilterOrderKnob.Value), app.HighCutoffFrequencyEditField.Value/(10000/2), 'high');
            case 'BandPass'
                [app.b, app.a] = butter(str2double(app.FilterOrderKnob.Value), [app.HighCutoffFrequencyEditField.Value/(10000/2) app.LowCutoffFrequencyEditField.Value/(10000/2)], 'bandpass');
            case 'BandStop'
                [app.b, app.a] = butter(str2double(app.FilterOrderKnob.Value), [app.HighCutoffFrequencyEditField.Value/(10000/2) app.LowCutoffFrequencyEditField.Value/(10000/2)], 'stop');
         end
        end

        % Button pushed function: STOPButton
        function StopButtonPushed(app, event)
        app.run = 0;
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            app.run = 1;
            ConnectButtonPushed(app, event)
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            refreshSerialPorts(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequest, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SerialPortDropDownLabel = uilabel(app.UIFigure);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.UIFigure);
            app.SerialPortDropDown.Items = {};
            app.SerialPortDropDown.Value = {};
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.FilterOrderKnobLabel = uilabel(app.UIFigure);
            app.FilterOrderKnobLabel.Text = 'Filter Order';
            app.FilterOrderKnob = uiknob(app.UIFigure, 'discrete');
            app.FilterOrderKnob.Items = {'1', '2', '3', '4', '5'};
            app.FilterOrderKnob.Value = '1';
            app.FilterOrderKnob_2Label = uilabel(app.UIFigure);
            app.FilterOrderKnob_2Label.Text = 'Filter Order';
            app.FilterTypeKnob = uiknob(app.UIFigure, 'discrete');
            app.FilterTypeKnob.Items = {'LowPass', 'HighPass', 'BandPass', 'StopBand'};
            app.FilterTypeKnob.Value = 'LowPass';
            app.HighCutoffFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.HighCutoffFrequencyEditFieldLabel.Text = 'High Cut-off Frequency';
            app.HighCutoffFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowCutoffFrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.LowCutoffFrequencyEditFieldLabel.Text = 'Low Cut-off Frequency';
            app.LowCutoffFrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FilterButton = uibutton(app.UIFigure, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.ConnectButton_2 = uibutton(app.UIFigure, 'push');
            app.ConnectButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton_2.Text = 'Connect';
            app.Label = uilabel(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sapa_bayabifinal_yusuf

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end